classdef visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        PlayButton                matlab.ui.control.Button
        ScenarioDropDownLabel     matlab.ui.control.Label
        ScenarioDropdown          matlab.ui.control.DropDown
        TransmitterDropDownLabel  matlab.ui.control.Label
        TxDropdown                matlab.ui.control.DropDown
        ReceiverDropDownLabel     matlab.ui.control.Label
        RxDropdown                matlab.ui.control.DropDown
        TimestepSliderLabel       matlab.ui.control.Label
        TimestepSlider            matlab.ui.control.Slider
        PauseButton               matlab.ui.control.Button
        TimestepSpinner           matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        scenarioName % name of the selected scenario
        
        timestepInfo
        
        outputPath
        visualizerPath
        ns3Path
        
        numNodes
        txIndex % starting from 1
        rxIndex % starting from 1
        
        roomPlotHandle
        raysPlotHandle
        nodesPlotHandle
        
        framerate = 20;
        playFlag = false;
        
        rayColors = [...
            0,0,0;...
            1,0,0;...
            0,1,0;...
            0,0,1];
        rayWidth = [3,2,1];
        
    end
    
    properties (SetObservable)
          currentTimestep
    end    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            clc
            addpath('raysAppFunctions', '../utils/')
            
            % Init
            app.ScenarioDropdown.Items = {'Indoor1',...
                'Indoor2',...
                'DenserScenario',...
                'SpatialSharing',...
                'L-Room',...
                'Outdoor1'}; % todo: make a fnc that reads the list of possible scenarios
            
            % Properties setup
            app.TimestepSpinner.RoundFractionalValues = 'on';
            
            addlistener(app,'currentTimestep','PostSet',@updateTimestep);
                        
            updateScenario(app)
        end

        % Value changed function: ScenarioDropdown
        function ScenarioDropdownValueChanged(app, event)
            updateScenario(app)
            raysAppPlotRoom(app)            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.playFlag = true;
            playScenario(app, event)
        end

        % Value changed function: TxDropdown
        function TxDropdownValueChanged(app, event)
            value = app.TxDropdown.Value;
            setupTxSlider(app, str2double(value)+1)
            plotFrame(app)
        end

        % Value changed function: RxDropdown
        function RxDropdownValueChanged(app, event)
            value = app.RxDropdown.Value;
            setupRxSlider(app, str2double(value)+1)
            plotFrame(app)
        end

        % Value changed function: TimestepSlider
        function TimestepSliderValueChanged(app, event)

        end

        % Callback function
        function TimeStepFieldValueChanged(app, event)
            
        end

        % Value changing function: TimestepSlider
        function TimestepSliderValueChanging(app, event)
            app.playFlag = false;
            roundedValue = round(event.Value);
            
            if roundedValue ~= app.currentTimestep
                app.currentTimestep = roundedValue;
            end
        end

        % Value changed function: TimestepSpinner
        function TimestepSpinnerValueChanged(app, event)

        end

        % Value changing function: TimestepSpinner
        function TimestepSpinnerValueChanging(app, event)
            app.playFlag = false;
            changingValue = event.Value;
            app.currentTimestep = changingValue;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.playFlag = false;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '', 'Interpreter', 'latex')
            xlabel(app.UIAxes, '', 'Interpreter', 'latex')
            ylabel(app.UIAxes, '', 'Interpreter', 'latex')
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ScenarioDropDownLabel = uilabel(app.UIFigure);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropdown = uidropdown(app.UIFigure);
            app.ScenarioDropdown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropdownValueChanged, true);
            app.TransmitterDropDownLabel = uilabel(app.UIFigure);
            app.TransmitterDropDownLabel.Text = 'Transmitter';
            app.TxDropdown = uidropdown(app.UIFigure);
            app.TxDropdown.ValueChangedFcn = createCallbackFcn(app, @TxDropdownValueChanged, true);
            app.ReceiverDropDownLabel = uilabel(app.UIFigure);
            app.ReceiverDropDownLabel.Text = 'Receiver';
            app.RxDropdown = uidropdown(app.UIFigure);
            app.RxDropdown.ValueChangedFcn = createCallbackFcn(app, @RxDropdownValueChanged, true);
            app.TimestepSliderLabel = uilabel(app.UIFigure);
            app.TimestepSliderLabel.Text = 'Time-step';
            app.TimestepSlider = uislider(app.UIFigure);
            app.TimestepSlider.ValueChangedFcn = createCallbackFcn(app, @TimestepSliderValueChanged, true);
            app.TimestepSlider.ValueChangingFcn = createCallbackFcn(app, @TimestepSliderValueChanging, true);
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.TimestepSpinner = uispinner(app.UIFigure);
            app.TimestepSpinner.ValueChangingFcn = createCallbackFcn(app, @TimestepSpinnerValueChanging, true);
            app.TimestepSpinner.ValueChangedFcn = createCallbackFcn(app, @TimestepSpinnerValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = visualizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end